classdef matLabFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Team10ClimateControlProjectLabel  matlab.ui.control.Label
        catIntro                       matlab.ui.control.Image
        Light2Switch                   matlab.ui.control.Switch
        Light2SwitchLabel              matlab.ui.control.Label
        catImage                       matlab.ui.control.Image
        DataFile4Button                matlab.ui.control.Button
        DataFile3Button                matlab.ui.control.Button
        DataFile2Button                matlab.ui.control.Button
        DataFile1Button                matlab.ui.control.Button
        TemperatureDataMenuLabel       matlab.ui.control.Label
        Light1Switch                   matlab.ui.control.Switch
        Light1SwitchLabel              matlab.ui.control.Label
        LightIntensitySlider           matlab.ui.control.Slider
        LightIntensitySliderLabel      matlab.ui.control.Label
        StopRecordingButton            matlab.ui.control.Button
        RecordTemperatureButton        matlab.ui.control.Button
        modeSwitch                     matlab.ui.control.Switch
        WindowSlider                   matlab.ui.control.Slider
        WindowSliderLabel              matlab.ui.control.Label
        LightIntensityGauge            matlab.ui.control.SemicircularGauge
        LightIntensityGaugeLabel       matlab.ui.control.Label
        TemperatureDegreesFGauge       matlab.ui.control.Gauge
        TemperatureDegreesFGaugeLabel  matlab.ui.control.Label
        PasswordFeedback               matlab.ui.control.EditField
        EnterButton                    matlab.ui.control.Button
        EnteryourpasswordLabel         matlab.ui.control.Label
        userPassword                   matlab.ui.control.NumericEditField
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        % Initialize Global Variables 
        a;
        s;
        TempDataArray; 
        fileNum;


        fileArray; 
        datesArray; 


    end
    
    methods (Access = private)
        
        function ledValue = automaticLight(app)
            % Automatically turns light on 
            v1 = readVoltage(app.a,'A1');

            % test 1
            pause(0.5); 
            ledValue =1- v1 / 5;
            writePWMDutyCycle(app.a, 'D11', ledValue);
            writePWMDutyCycle(app.a, 'D5', ledValue);


        end
        
                  function tempK = SteinhartVoltagetoTemp5V(~,voltage)
                            %This function uses an equation called the “Steinhart relation” to convert the voltage
                            %read by Arduino to a temperature in Kelvin
                            SeriesResistor = 10000; % the 10K ohm resistor is used in the circuit
                            ThermistorResistance = 10000; %The resistance of thermistor
                             %With NTC thermistors, resistance decreases as temperature increases
                              resistance = SeriesResistor .* voltage ./ (5 - voltage);
                                %Constants used in Steinhart equation
                                A1 = 3.354016E-03;
                                B1 = 2.569850E-04;
                                C1 = 2.620131E-06;
                                D1 = 6.383091E-08;
                                resRatio = log(resistance ./ ThermistorResistance);
                                tempK = 1 ./ (A1 + B1 .* resRatio + C1 .* resRatio .^ 2 + D1 .* resRatio .^ 3);
                  end


                              function result = finalTemp(app)

                        % Read current voltage value
                         v = readVoltage(app.a, 'A0');
                         % Calculate temperature from voltage (based on data sheet)
                          tempK =  app.SteinhartVoltagetoTemp5V(v);
                         %convert the temperature to from Kelvin to Celcius
                        tempC = (tempK - 273.15);
                        %convert the temperature from Kelvin to Fahrenheit
                        tempF = 9/5 * tempC + 32;

                       result = tempF;


            end
        
        function plotTemp(app, fileNum)
            % Plots time vs temp 

            fidDataTemp = fopen(app.fileArray(fileNum), "r"); 
            % Reads Data from file 
            data = textscan(fidDataTemp, "%s %s"); 


            % Grabs time and temp from data
            time = double(string(data{1}(2:end)))'; 
            temp = double(string(data{2}(2:end)))';


            % Plots Data 
            plot(time,temp, 'LineWidth', 5); 
            title(sprintf("Temperature Vs Time for %s", app.datesArray(fileNum))); 
            xlabel("Time (s)"); 
            ylabel("Temperature (Degrees F)"); 


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            clf; clc; 
            
            % Define Global Variables
            app.a = arduino;
            app.s = servo(app.a, "D10");
            
            % Array for Data Files
            app.fileArray = string(zeros(1,4));  
            
            % Array for Dates 
            app.datesArray = string(zeros(1,4)); 


            % Iterator for files 
            app.fileNum = 0; 


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: userPassword
        function userPasswordValue(app, event)
            %userPasswordValue = app.userPassword.Value;


        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
          
          % Check if correct answer was entered


         if app.userPassword.Value == 1234


             % Hide Intro Cat
             app.catIntro.Visible = "Off"; 
             app.Team10ClimateControlProjectLabel.Visible = "Off"; 


             % Show Cat Photo
             app.catImage.Visible = "On"; 


             % Turns visibility on for all the gauges

             app.TemperatureDegreesFGauge.Visible = 'on'; 
             app.TemperatureDegreesFGaugeLabel.Visible = 'on'; 


             app.LightIntensityGauge.Visible = 'on'; 
             app.LightIntensityGaugeLabel.Visible = 'on'; 


             % Manual Mode vs Automatic 

            app.modeSwitch.Visible = 'on'; 


             % Window Slider Visibility 

             app.WindowSlider.Visible = 'on'; 
             app.WindowSliderLabel.Visible = 'on';


             app.RecordTemperatureButton.Visible = 'on'; 


             % Sets Visibility to Password fields to off
             app.EnterButton.Visible = 'off'; 
             app.userPassword.Visible = 'off'; 


             % Changes feedback to Green 
             app.PasswordFeedback.FontColor = '#52B013'; 
             % Tells user password was correct
             app.PasswordFeedback.Value = "Your Password was correct! Welcome!";

             % Sets visibility of Password Feedback to on
             app.PasswordFeedback.Visible = 'on'; 
              
             % Hides Password Feedback app.PasswordFeedback.Visible = 'off'; 
             app.EnteryourpasswordLabel.Visible = 'off';


            % Allow user to acess gauges 


            % NOTE: NO CALLBACKS NEEDED FOR ANALOG GAUGES 


                     % Manual Mode Vs Automatic 
                     % Keeps Running code

                       while app.modeSwitch.Value == "Automatic Mode" 


                                    % Reads Light Intensity 
                                    app.LightIntensityGauge.Value = automaticLight(app); 


                                      % Pauses before changing light
                                      pause(0.1);


                                      % Records Temperature on gauge
                                      app.TemperatureDegreesFGauge.Value =  finalTemp(app); 


                       end


         else

                
             % Sets visibility of Password Feedback to on
             app.PasswordFeedback.Visible = 'on'; 

             % Changes feedback to Red 
             app.PasswordFeedback.BackgroundColor = '#D6C6C3';
             app.PasswordFeedback.FontColor = '#C21306'; 
             % Tells user password was incorrect & to try again
             app.PasswordFeedback.Value = "Your Password was incorrect. Please try again";


         end


        end

        % Value changed function: PasswordFeedback
        function PasswordFeedbackValueChanged(app, event)
            %value = app.PasswordFeedback.Value;


        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            %value = app.WindowSlider.Value;

            % In 0:0.1:1 not degrees
            angle = app.WindowSlider.Value; 
            % Slider is mapped from array 0:0.1:1
            writePosition(app.s, angle); 
            current_pos = readPosition(app.s); 
            current_pos = current_pos * 180; 
            % Position from 0 to 180 degrees
            fprintf('Current motor position is %d degrees\n', current_pos);


        end

        % Value changed function: modeSwitch
        function modeSwitchValueChanged(app, event)
            %value = app.modeSwitch.Value;
        
            % Mode switch between automatic and manual 
        

            if app.modeSwitch.Value == "Automatic Mode"
                
                % Turns on window slider for automatic mode
                app.WindowSlider.Visible = "on";
                app.WindowSliderLabel.Visible = "on";

                % Turns off slider for Manual Change
                app.LightIntensitySlider.Visible = "off"; 
                app.LightIntensitySliderLabel.Visible = "off";

                % Hides Light Switches for automatic Mode 
                % Hides First Light Switch 

                app.Light1Switch.Visible = "off"; 
                app.Light1SwitchLabel.Visible = "off"; 

                app.Light2Switch.Visible = "off"; 
                app.Light2SwitchLabel.Visible = "off";


                % Automatic Mode is closed 
                writePosition(app.s, 0);
                
                % Sets window slider to 0 
                app.WindowSlider.Value = 0; 


                    while app.modeSwitch.Value == "Automatic Mode"
                      % Continues to read light
                     app.LightIntensityGauge.Value = automaticLight(app); 

                     % Reads Temp 
                     app.TemperatureDegreesFGauge.Value = finalTemp(app); 

                    end 


            elseif app.modeSwitch.Value == "Manual Mode"


                % Automatically turns off lights
                writePWMDutyCycle(app.a, 'D11', 0);
                 writePWMDutyCycle(app.a, 'D5', 0);
                

                % Set Light Intensity Gauge to zero 
                app.LightIntensityGauge.Value = 0; 
                % Turns on slider for Manual Change
                app.LightIntensitySlider.Visible = "on"; 
                app.LightIntensitySliderLabel.Visible = "on";


                % Opens Servo Motor
                writePosition(app.s, 1);
                % Sets Window Slider Value to 1
                 app.WindowSlider.Value = 1;
                % Change Light Intensity According to user


                % Light Switches for user to manually turn on and off the
                % light

                app.Light1Switch.Visible = "on"; 
                app.Light1SwitchLabel.Visible = "on"; 

                app.Light2Switch.Visible = "on"; 
                app.Light2SwitchLabel.Visible = "on"; 


                % Test 

                while app.modeSwitch.Value == "Manual Mode" 
                

                     % Continues to read light
                     %app.LightIntensityGauge.Value = automaticLight(app);
                      % Reads Temp 
                     app.TemperatureDegreesFGauge.Value = finalTemp(app);

                end 


            else 

                % Do nothing

            end 


        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
           app.StopRecordingButton.Visible = 'off'; 
           app.RecordTemperatureButton.Visible = 'on';
           fclose("all"); 

            switch app.fileNum

                case 1
                    % Button for file 1
                    app.TemperatureDataMenuLabel.Visible = "On"; 
                    
                    % Grabs time for button Name 
                    buttonName = ['Data File ' char(app.datesArray(app.fileNum))]; 
                    % Change Button Name
                    app.DataFile1Button.Text = buttonName; 
                    % Show Button for Data File 
                    app.DataFile1Button.Visible = "On"; 
            

                case 2
                    % Button for file 2

                    buttonName = ['Data File ' char(app.datesArray(app.fileNum))]; 
                    app.DataFile2Button.Text = buttonName;
                    app.DataFile2Button.Visible = "On"; 
                case 3
                    % Button for file 3


                    buttonName = ['Data File ' char(app.datesArray(app.fileNum))]; 
                    app.DataFile3Button.Text = buttonName;
                    app.DataFile3Button.Visible = "On";
                case 4

                    buttonName = ['Data File ' char(app.datesArray(app.fileNum))]; 
                    app.DataFile4Button.Text = buttonName;
                    % Button for file 4
                    app.DataFile4Button.Visible = "On"; 


                otherwise
                    fprintf("Not working?\n"); 
            end 


        end

        % Button pushed function: RecordTemperatureButton
        function RecordTemperatureButtonPushed(app, event)
            app.RecordTemperatureButton.Visible = 'off'; 
            app.StopRecordingButton.Visible = 'on'; 

           
            if length(app.fileArray) == (app.fileNum)
                errordlg('You have recorded the maximum amount of files. Please upgrade to premium to record more temperature files.','Header');
                % Close files 
                fidClose = fclose("all"); 

                if fidClose == 0 
                    % Do Nothing 
                else 
                    % If files don't close properly. 
                   errordlg("Files did not close!"); 
                end


                % Hide Record & Stop Recording buttons 
                app.RecordTemperatureButton.Visible = 'off'; 
                app.StopRecordingButton.Visible = 'off'; 


            else


                   % Adds 1 to file num (iterator)
                   app.fileNum = app.fileNum + 1;
                   % Gets Current Time 
                   dateCurrent = string(datetime('now'));
                
                   % Grabs date for button 
                   app.datesArray(app.fileNum) = dateCurrent; 

                   % Puts Data in a character array
                   dateCurrent = char(strrep(dateCurrent, " ", "-")); 
                   % adds .dat for file 
                   dateCurrent = [ dateCurrent '.dat']; 


                   % Turns Back into string
                   dateCurrent = string(dateCurrent);

                   dateCurrent = strrep(dateCurrent, ":", "-"); 


                   % Adds file to pre-defined array

                   
                   app.fileArray(app.fileNum) = dateCurrent; 

                   % Opens new file 

                   disp(dateCurrent); 

                   fidTempData = fopen(app.fileArray(app.fileNum), "w"); 


                   % Prints Headers
                   fprintf(fidTempData, "Time Temp\n"); 


                   if fidTempData == -1 

                       fprintf("Your File Did not Create/Open \n"); 

                   else 

        
                        time = 0; 
        
                        while app.StopRecordingButton.Visible == "on"


                        % Also send data to temperature gauge 
                                              
                        app.TemperatureDegreesFGauge.Value = finalTemp(app);
        
                        % Read current voltage value
                        v = readVoltage(app.a, 'A0');
                        % Calculate temperature from voltage (based on data sheet)
                        tempK = app.SteinhartVoltagetoTemp5V(v);
                        %convert the temperature to from Kelvin to Celcius
                        tempC = tempK - 273.15;
                        %convert the temperature from Kelvin to Fahrenheit
                        tempF = 9/5 * tempC + 32;


                          % Prints data to file 
                          fprintf(fidTempData, "%d %s\n",time, string(tempF) );


                               % Time iterator 
                                time = time + 1; 
                                
                                % Pauses for 1 second ( 1 hz)  


                                % In While loop to keep on working
                                app.LightIntensityGauge.Value =   automaticLight(app); 
                                pause(1);


                        end

                   end 


            end


        end

        % Value changed function: LightIntensitySlider
        function LightIntensitySliderValueChanged(app, event)
            % value = app.LightIntensitySlider.Value;


            if (app.Light1Switch.Value == "On") && (app.Light2Switch.Value == "On") 

                    % Light Intensity Slider for Manual Mode for light
                    % Intensity for Light 1 & 2
                      writePWMDutyCycle(app.a, 'D11', app.LightIntensitySlider.Value);
        
                      writePWMDutyCycle(app.a, 'D5', app.LightIntensitySlider.Value);
        
                      app.LightIntensityGauge.Value = app.LightIntensitySlider.Value; 
        
                      if app.LightIntensitySlider.Value == 0 
                        % Turn off light switch if user sets intensity to zero 
        
                        app.Light1Switch.Value = "Off"; 
                        app.Light2Switch.Value = "Off"; 
        
                      else 
        
                          app.Light1Switch.Value = "On"; 
                          app.Light2Switch.Value = "On";
        
                      end

            elseif (app.Light1Switch.Value == "On") && (app.Light2Switch.Value == "Off")
                    % If only Light 1 is on 
                    writePWMDutyCycle(app.a, 'D11', app.LightIntensitySlider.Value);
                    app.LightIntensityGauge.Value = app.LightIntensitySlider.Value; 

                    if app.LightIntensitySlider.Value == 0 
                        app.Light1Switch.Value = "Off";
                    else 
                        app.Light1Switch.Value = "On";
                    end 


            elseif (app.Light1Switch.Value == "Off") && (app.Light2Switch.Value == "On") 
                  % If only light 2 is on 
                    % If only Light 1 is on 
                    writePWMDutyCycle(app.a, 'D5', app.LightIntensitySlider.Value);
                    app.LightIntensityGauge.Value = app.LightIntensitySlider.Value; 

                    if app.LightIntensitySlider.Value == 0 
                        app.Light2Switch.Value = "Off";
                    else 
                        app.Light2Switch.Value = "On";
                    end
            else 
                % If both are off and slider is changed then they both turn
                % on

                if app.LightIntensitySlider.Value ~= 0 
                    % Turn on swithces 
                    app.Light1Switch.Value = "On"; 
                    app.Light2Switch.Value = "On"; 

                    % Intensity Depends on slider again

                    % Light 1
                    writePWMDutyCycle(app.a, 'D11', app.LightIntensitySlider.Value);
                    % Light 2
                    writePWMDutyCycle(app.a, 'D5', app.LightIntensitySlider.Value);
                    % Gauge for Light
                    app.LightIntensityGauge.Value = app.LightIntensitySlider.Value; 


                else 
                           % Turn off lights 
                           % Light 1: 
                           writePWMDutyCycle(app.a, 'D11', 0);
                           % Light 2: 
                           writePWMDutyCycle(app.a, 'D5', 0);

                           app.LightIntensityGauge.Value = 0; 
                           app.LightIntensitySlider.Value = 0; 


                end


            end 


        end

        % Value changed function: Light1Switch
        function Light1SwitchValueChanged(app, event)
            %value = app.Light1Switch.Value;

            % Light Switch for Light 1 Value 

                 if app.Light1Switch.Value == "On"


                                 % Defualt Set to 0.1 for light 1
                                 app.LightIntensitySlider.Value = 0.1; 
                                writePWMDutyCycle(app.a, 'D11', 0.1);

                                % Set Gauge to 0.1
                                app.LightIntensityGauge.Value = 0.1; 


                elseif app.Light1Switch.Value == "Off"
                        
                     
                    % Turn off light 1
                    writePWMDutyCycle(app.a, 'D11', 0);

                    % Set Slider to Light intensity of zero 
                    app.LightIntensitySlider.Value = 0; 

                    % Set Light Intensity Gauge to Zero
                    app.LightIntensityGauge.Value = 0;


                else 

                    % Do Nothing
                end
            
        end

        % Button pushed function: DataFile1Button
        function DataFile1ButtonPushed(app, event)
            % Button 1 Data File Pressed

           app.plotTemp(1);


        end

        % Button pushed function: DataFile2Button
        function DataFile2ButtonPushed(app, event)
             % Button 2 Data File Pressed    
            app.plotTemp(2);

        end

        % Button pushed function: DataFile3Button
        function DataFile3ButtonPushed(app, event)
             % Button 3 Data File Pressed
             app.plotTemp(3);

        end

        % Button pushed function: DataFile4Button
        function DataFile4ButtonPushed(app, event)
             % Button 4 Data File Pressed
              app.plotTemp(4);
                
        end

        % Value changed function: Light2Switch
        function Light2SwitchValueChanged(app, event)
            %value = app.Light2Switch.Value;
            % Switch for light 1    


            % Light Switch for Light 1 Value 

                 if app.Light2Switch.Value == "On"


                                 % Defualt Set to 0.1 for light 1
                                 app.LightIntensitySlider.Value = 0.1; 
                                writePWMDutyCycle(app.a, 'D5', 0.1);

                                % Set Gauge to 0.1
                                app.LightIntensityGauge.Value = 0.1; 


                elseif app.Light1Switch.Value == "Off"
                        
                     
                    % Turn off light 1
                    writePWMDutyCycle(app.a, 'D5', 0);

                    % Set Slider to Light intensity of zero 
                    app.LightIntensitySlider.Value = 0; 

                    % Set Light Intensity Gauge to Zero
                    app.LightIntensityGauge.Value = 0;


                else 

                    % Do Nothing
                end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.userPassword = uieditfield(app.UIFigure, 'numeric');
            app.userPassword.AllowEmpty = 'on';
            app.userPassword.ValueChangedFcn = createCallbackFcn(app, @userPasswordValue, true);
            app.userPassword.Placeholder = 'Ex: 2354';
            app.userPassword.Value = [];
            app.EnteryourpasswordLabel = uilabel(app.UIFigure);
            app.EnteryourpasswordLabel.Text = 'Enter your password';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.PasswordFeedback = uieditfield(app.UIFigure, 'text');
            app.PasswordFeedback.ValueChangedFcn = createCallbackFcn(app, @PasswordFeedbackValueChanged, true);
            app.PasswordFeedback.Editable = 'off';
            app.PasswordFeedback.Visible = 'off';
            app.TemperatureDegreesFGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureDegreesFGaugeLabel.Visible = 'off';
            app.TemperatureDegreesFGaugeLabel.Text = 'Temperature (Degrees F)';
            app.TemperatureDegreesFGauge = uigauge(app.UIFigure, 'circular');
            app.TemperatureDegreesFGauge.Visible = 'off';
            app.LightIntensityGaugeLabel = uilabel(app.UIFigure);
            app.LightIntensityGaugeLabel.Visible = 'off';
            app.LightIntensityGaugeLabel.Text = 'Light Intensity';
            app.LightIntensityGauge = uigauge(app.UIFigure, 'semicircular');
            app.LightIntensityGauge.Limits = [0 1];
            app.LightIntensityGauge.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.LightIntensityGauge.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.LightIntensityGauge.Visible = 'off';
            app.LightIntensityGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WindowSliderLabel = uilabel(app.UIFigure);
            app.WindowSliderLabel.Visible = 'off';
            app.WindowSliderLabel.Text = 'Window Slider';
            app.WindowSlider = uislider(app.UIFigure);
            app.WindowSlider.Limits = [0 1];
            app.WindowSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.WindowSlider.MajorTickLabels = {'0', '36', '72', '108', '144', '180'};
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.WindowSlider.Visible = 'off';
            app.modeSwitch = uiswitch(app.UIFigure, 'slider');
            app.modeSwitch.Items = {'Manual Mode', 'Automatic Mode'};
            app.modeSwitch.ValueChangedFcn = createCallbackFcn(app, @modeSwitchValueChanged, true);
            app.modeSwitch.Visible = 'off';
            app.modeSwitch.Value = 'Automatic Mode';
            app.RecordTemperatureButton = uibutton(app.UIFigure, 'push');
            app.RecordTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @RecordTemperatureButtonPushed, true);
            app.RecordTemperatureButton.Visible = 'off';
            app.RecordTemperatureButton.Text = 'Record Temperature';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Visible = 'off';
            app.StopRecordingButton.Text = 'Stop Recording';
            app.LightIntensitySliderLabel = uilabel(app.UIFigure);
            app.LightIntensitySliderLabel.Visible = 'off';
            app.LightIntensitySliderLabel.Text = 'Light Intensity';
            app.LightIntensitySlider = uislider(app.UIFigure);
            app.LightIntensitySlider.Limits = [0 1];
            app.LightIntensitySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.LightIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @LightIntensitySliderValueChanged, true);
            app.LightIntensitySlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LightIntensitySlider.Visible = 'off';
            app.Light1SwitchLabel = uilabel(app.UIFigure);
            app.Light1SwitchLabel.Visible = 'off';
            app.Light1SwitchLabel.Text = 'Light 1';
            app.Light1Switch = uiswitch(app.UIFigure, 'slider');
            app.Light1Switch.ValueChangedFcn = createCallbackFcn(app, @Light1SwitchValueChanged, true);
            app.Light1Switch.Visible = 'off';
            app.TemperatureDataMenuLabel = uilabel(app.UIFigure);
            app.TemperatureDataMenuLabel.Visible = 'off';
            app.TemperatureDataMenuLabel.Text = 'Temperature Data Menu';
            app.DataFile1Button = uibutton(app.UIFigure, 'push');
            app.DataFile1Button.ButtonPushedFcn = createCallbackFcn(app, @DataFile1ButtonPushed, true);
            app.DataFile1Button.Visible = 'off';
            app.DataFile1Button.Text = 'Data File 1';
            app.DataFile2Button = uibutton(app.UIFigure, 'push');
            app.DataFile2Button.ButtonPushedFcn = createCallbackFcn(app, @DataFile2ButtonPushed, true);
            app.DataFile2Button.Visible = 'off';
            app.DataFile2Button.Text = 'Data File 2';
            app.DataFile3Button = uibutton(app.UIFigure, 'push');
            app.DataFile3Button.ButtonPushedFcn = createCallbackFcn(app, @DataFile3ButtonPushed, true);
            app.DataFile3Button.Visible = 'off';
            app.DataFile3Button.Text = 'Data File 3';
            app.DataFile4Button = uibutton(app.UIFigure, 'push');
            app.DataFile4Button.ButtonPushedFcn = createCallbackFcn(app, @DataFile4ButtonPushed, true);
            app.DataFile4Button.Visible = 'off';
            app.DataFile4Button.Text = 'Data File 4';
            app.catImage = uiimage(app.UIFigure);
            app.catImage.Visible = 'off';
            app.catImage.ImageSource = fullfile(pathToMLAPP, 'catPhoto.jpg');
            app.Light2SwitchLabel = uilabel(app.UIFigure);
            app.Light2SwitchLabel.Visible = 'off';
            app.Light2SwitchLabel.Text = 'Light 2';
            app.Light2Switch = uiswitch(app.UIFigure, 'slider');
            app.Light2Switch.ValueChangedFcn = createCallbackFcn(app, @Light2SwitchValueChanged, true);
            app.Light2Switch.Visible = 'off';
            app.catIntro = uiimage(app.UIFigure);
            app.catIntro.ImageSource = fullfile(pathToMLAPP, 'catIntro.jpeg');
            app.Team10ClimateControlProjectLabel = uilabel(app.UIFigure);
            app.Team10ClimateControlProjectLabel.Text = 'Team 10 Climate Control Project';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matLabFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end